classdef covid_19_data < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COVID19DataUIFigure        matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        CountryListBoxLabel        matlab.ui.control.Label
        CountryListBox             matlab.ui.control.ListBox
        StateorRegionLabel         matlab.ui.control.Label
        StateorRegionListBox       matlab.ui.control.ListBox
        AveragedofDaysSliderLabel  matlab.ui.control.Label
        AveragedofDaysSlider       matlab.ui.control.Slider
        DataToPlotButtonGroup      matlab.ui.container.ButtonGroup
        CasesButton                matlab.ui.control.RadioButton
        DeathButton                matlab.ui.control.RadioButton
        BothButton                 matlab.ui.control.RadioButton
        OptionButtonGroup          matlab.ui.container.ButtonGroup
        CumulativeButton           matlab.ui.control.RadioButton
        DailyButton                matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        data                    % Preprocessed Data
        state_struct            % State Properties for Each Countries
        countries               % List of countries
        current_country         % Index for Current Country
        current_state           % Index for Current State
        
        % Obtained Data
        confirmed_cases_cum     % Cumulative Confirmed Cases to be plotted
        death_cases_cum         % Cumulative Death cases to be plotted
        confirmed_cases_day     % Daily Confirmed Cases to be plotted
        death_cases_day         % Daily Death cases to be plotted
        
        % Plot Data
        dates                   % Dates on the x-axis
        avg_confirmed_cases_cum     % Cumulative Confirmed Cases to be plotted
        avg_death_cases_cum         % Cumulative Death cases to be plotted
        avg_confirmed_cases_day     % Daily Confirmed Cases to be plotted
        avg_death_cases_day         % Daily Death cases to be plotted
        
        % current values
        averaging_value
        data_to_plot_value
        option_value
        
    end
    
    methods (Access = private)
        
        function app = load_countries(app)
            app.countries = unique({app.data.country});
            app.countries(2:end+1) = app.countries;
            app.countries{1} = 'Global';
            
            app.CountryListBox.Items = app.countries;
            app.CountryListBox.Value = 'Global';
            app.current_country = find(strcmp(app.countries, app.CountryListBox.Value));
            
        end
        
        function app = load_states(app)
            app.StateorRegionListBox.Items = app.state_struct(app.current_country).States;
            % app.StateorRegionListBox.value = app.StateorRegionListBox.Items(1);
            app.current_state = find(strcmp(app.StateorRegionListBox.Value, app.state_struct(app.current_country).States));
        end
        
        function plot_death_cases_data(app)
            if app.CumulativeButton.Value
                plot(app.UIAxes, app.dates, app.avg_death_cases_cum, '-r');
            elseif app.DailyButton.Value
                plot(app.UIAxes, app.dates, app.avg_death_cases_day, '-r');
            end
        end
        
        function plot_confirmed_cases_data(app)
            if app.CumulativeButton.Value
                plot(app.UIAxes, app.dates, app.avg_confirmed_cases_cum, '-b');
            elseif app.DailyButton.Value
                plot(app.UIAxes, app.dates, app.avg_confirmed_cases_day, '-b');
            end
        end
        
        function plot_data(app)
            
            cla(app.UIAxes);
            
            % Determine Cummulative or Daily
            if app.CumulativeButton.Value
                cum_or_day = 'Cumulative';
            elseif app.DailyButton.Value
                cum_or_day = 'Daily';
            end
            
            % Determine State or Country Name
            if app.current_state == 1
                country_or_state = app.countries{app.current_country};
            else
                country_or_state = app.state_struct(app.current_country).States{app.current_state};
            end
            
            % Plotting Data
            
            if app.CasesButton.Value
                
                yyaxis(app.UIAxes,'left')
                plot_confirmed_cases_data(app)
                ylabel(app.UIAxes, '# of Cases Confirmed')
                
                yyaxis(app.UIAxes,'right')
                cla(app.UIAxes);
                
                data_plot_type = 'Cases';
                
            elseif app.DeathButton.Value
                
                yyaxis(app.UIAxes,'left')
                cla(app.UIAxes);
                
                yyaxis(app.UIAxes,'right')
                plot_death_cases_data(app)
                ylabel(app.UIAxes, '# of Death')
                
                data_plot_type = 'Death';
                
            elseif app.BothButton.Value
                
                yyaxis(app.UIAxes,'left')
                plot_confirmed_cases_data(app)
                ylabel(app.UIAxes, '# of Cases Confirmed')
                
                yyaxis(app.UIAxes,'right')
                plot_death_cases_data(app)
                ylabel(app.UIAxes, '# of Death')
                
                data_plot_type = 'Cases and Death';
            end
            title_name = [cum_or_day,' Number of ', data_plot_type,' in ', country_or_state,' (', num2str(app.AveragedofDaysSlider.Value),'-Day Mean)'];
            title(app.UIAxes, title_name)
            hold(app.UIAxes, 'off')
        end
        
        function app = update_plot_data(app)
            app.confirmed_cases_cum = app.state_struct(app.current_country).Confirmed_Cases_Cum{app.current_state};
            app.confirmed_cases_day = app.state_struct(app.current_country).Confirmed_Cases_Day{app.current_state};
            app.death_cases_cum = app.state_struct(app.current_country).Death_Cases_Cum{app.current_state};
            app.death_cases_day = app.state_struct(app.current_country).Death_Cases_Day{app.current_state};
            
            app = compute_averages(app);
        end

        function [data, dates, state_struct] = load_data(app)
            loaded_data = load('covid_data.mat', 'covid_data');
            covid_data = loaded_data.covid_data;
            
            % Gather Country Data
            data = struct();
            
            for i = 2:size(covid_data, 1)
                data(i-1).country = covid_data{i, 1};
                data(i-1).state = covid_data{i, 2};
            end
            
            % Gather Date Headers
            dates = datetime();
            for i = 3:size(covid_data,2)
                dates(i-2) = convert_to_date(covid_data(1, i));
            end
            
            % Getting State For Each Country
            x = {data.country};
            y = {data.state};
            
            state_struct = struct('Country', {'Global'}, 'States', {{'All'}}, 'Confirmed_Cases_Cum', {[]}, 'Death_Cases_Cum', {[]}, 'Confirmed_Cases_Day', {[]}, 'Death_Cases_Day', {[]});
            
            count = 1;
            for i = 1:length(x)
                if ~ismember(x{i}, {state_struct.Country})
                    count = count + 1;
                    state_struct(count).Country = x{i};
                    state_struct(count).Confirmed_Cases_Cum = {};
                    state_struct(count).Death_Cases_Cum = {};
                    state_struct(count).Confirmed_Cases_Day = {};
                    state_struct(count).Death_Cases_Day = {};
                end
                
                if strcmp(y{i}, '')
                    u = state_struct(count).States;
                    u{end+1} = 'All';
                    state_struct(count).States = u;
                else
                    u = state_struct(count).States;
                    u{end+1} = y{i};
                    state_struct(count).States = u;
                end
                
                death_cases = zeros(1, size(covid_data,2)-2);
                confirmed_cases = zeros(1, size(covid_data,2)-2);
                for k = 3:size(covid_data,2)
                    covid_case_data = covid_data{i+1, k};
                    confirmed_cases(k-2) = covid_case_data(1);
                    death_cases(k-2) = covid_case_data(2);
                end
                
                state_struct(count).Confirmed_Cases_Cum{end+1} = confirmed_cases;
                state_struct(count).Death_Cases_Cum{end+1} = death_cases;
                
                state_struct(count).Confirmed_Cases_Day{end+1} = reverse_cum_vec(app, confirmed_cases);
                state_struct(count).Death_Cases_Day{end+1} = reverse_cum_vec(app, death_cases);
            end
            
            % Compute Global Data
            confirmed_cases = zeros(1, size(covid_data, 2)-2);
            death_cases = zeros(1, size(covid_data, 2)-2);
            for k = 3:size(covid_data, 2)
                for i = 2:size(covid_data, 1)
                    if strcmp(covid_data{i, 2}, '')
                        u = covid_data{i, k};
                        
                        confirmed_cases(k-2) = confirmed_cases(k-2) + u(1);
                        death_cases(k-2) = death_cases(k-2) + u(2);
                    end
                end
            end
            
            state_struct(1).Confirmed_Cases_Cum = {confirmed_cases};
            state_struct(1).Death_Cases_Cum = {death_cases};
            
            state_struct(1).Confirmed_Cases_Day = {reverse_cum_vec(app, confirmed_cases)};
            state_struct(1).Death_Cases_Day = {reverse_cum_vec(app, death_cases)};
        end
        
        function reversed_cumsum = reverse_cum_vec(app, cumsumvec)
            reversed_cumsum = zeros(1, length(cumsumvec));
            reversed_cumsum(1) = cumsumvec(1);
            for i = 2:length(cumsumvec)
                reversed_cumsum(i) = cumsumvec(i) - cumsumvec(i-1);
                n = 1;
                while reversed_cumsum(i) < 0
                    reversed_cumsum(i) = (cumsumvec(i) - cumsumvec(i-(n+1)))/(n+1);
                    n = n + 1;
                end
            end
        end
        
        function app = compute_averages(app)
            
            app.avg_death_cases_cum = movmean(app.death_cases_cum, app.averaging_value);
            app.avg_death_cases_day = movmean(app.death_cases_day, app.averaging_value);
            app.avg_confirmed_cases_cum = movmean(app.confirmed_cases_cum, app.averaging_value);
            app.avg_confirmed_cases_day = movmean(app.confirmed_cases_day, app.averaging_value);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load Data
            [app.data, app.dates, app.state_struct] = load_data(app);
            
            % Load Countries
            app = load_countries(app);
            
            % Load States
            app = load_states(app);
            
            % Plot Data
            app.averaging_value = app.AveragedofDaysSlider.Value;
            app = update_plot_data(app);
            app = compute_averages(app);
            plot_data(app);
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            app.current_country = find(strcmp(app.countries, app.CountryListBox.Value));
            app = load_states(app);
            app = update_plot_data(app);
            plot_data(app);
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            app.current_state = find(strcmp(app.StateorRegionListBox.Value, app.state_struct(app.current_country).States));
            app = update_plot_data(app);
            plot_data(app);
        end

        % Selection changed function: DataToPlotButtonGroup
        function DataToPlotButtonGroupSelectionChanged(app, event)
            app.data_to_plot_value = app.DataToPlotButtonGroup.SelectedObject.Text;
            plot_data(app);
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.option_value = app.OptionButtonGroup.SelectedObject.Text;
            plot_data(app);
        end

        % Value changed function: AveragedofDaysSlider
        function AveragedofDaysSliderValueChanged(app, event)
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(app.AveragedofDaysSlider.Value - event.Source.MinorTicks(:)));
            % move the slider to that option
            app.AveragedofDaysSlider.Value = event.Source.MinorTicks(minIdx);
            
            app.averaging_value = app.AveragedofDaysSlider.Value;
            app = compute_averages(app);
            plot_data(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COVID19DataUIFigure = uifigure('Visible', 'off');
            app.COVID19DataUIFigure.Name = 'COVID-19 Data';
            app.UIAxes = uiaxes(app.COVID19DataUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Years')
            ylabel(app.UIAxes, '')
            app.CountryListBoxLabel = uilabel(app.COVID19DataUIFigure);
            app.CountryListBoxLabel.Text = 'Country';
            app.CountryListBox = uilistbox(app.COVID19DataUIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateorRegionLabel = uilabel(app.COVID19DataUIFigure);
            app.StateorRegionLabel.Text = {'State or'; ' Region'};
            app.StateorRegionListBox = uilistbox(app.COVID19DataUIFigure);
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.AveragedofDaysSliderLabel = uilabel(app.COVID19DataUIFigure);
            app.AveragedofDaysSliderLabel.Text = {'Averaged '; '# of Days'};
            app.AveragedofDaysSlider = uislider(app.COVID19DataUIFigure);
            app.AveragedofDaysSlider.Limits = [1 15];
            app.AveragedofDaysSlider.MajorTicks = [1 5 10 15];
            app.AveragedofDaysSlider.ValueChangedFcn = createCallbackFcn(app, @AveragedofDaysSliderValueChanged, true);
            app.AveragedofDaysSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.AveragedofDaysSlider.Value = 1;
            app.DataToPlotButtonGroup = uibuttongroup(app.COVID19DataUIFigure);
            app.DataToPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataToPlotButtonGroupSelectionChanged, true);
            app.DataToPlotButtonGroup.Title = 'Data To Plot';
            app.CasesButton = uiradiobutton(app.DataToPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.DeathButton = uiradiobutton(app.DataToPlotButtonGroup);
            app.DeathButton.Text = 'Death';
            app.BothButton = uiradiobutton(app.DataToPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.BothButton.Value = true;
            app.OptionButtonGroup = uibuttongroup(app.COVID19DataUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.DailyButton.Value = true;
            % Show the figure after all components are created
            app.COVID19DataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = covid_19_data

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COVID19DataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COVID19DataUIFigure)
        end
    end
end